classdef App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        ImageConvolutionTab        matlab.ui.container.Tab
        ProcessButton              matlab.ui.control.Button
        InsertImageButton          matlab.ui.control.Button
        Image                      matlab.ui.control.Image
        MaskEditField              matlab.ui.control.EditField
        MaskEditFieldLabel         matlab.ui.control.Label
        ResultImageLabel           matlab.ui.control.Label
        OriginalImageLabel         matlab.ui.control.Label
        InputImage                 matlab.ui.control.Image
        ResultImage                matlab.ui.control.Image
        ImageSmoothingBlurringTab  matlab.ui.container.Tab
        TabGroup2                  matlab.ui.container.TabGroup
        SpatialTab                 matlab.ui.container.Tab
        FilterDropDown             matlab.ui.control.DropDown
        FilterDropDownLabel        matlab.ui.control.Label
        SigmaSpinner               matlab.ui.control.Spinner
        SpinnerLabel               matlab.ui.control.Label
        MaskDimensionSpinner       matlab.ui.control.Spinner
        MaskDimensionSpinnerLabel  matlab.ui.control.Label
        FrequencyTab               matlab.ui.container.Tab
        PaddingLabel               matlab.ui.control.Label
        PaddingCheckBox            matlab.ui.control.CheckBox
        FilterDropDown_2           matlab.ui.control.DropDown
        FilterDropDown_2Label      matlab.ui.control.Label
        NSpinner                   matlab.ui.control.Spinner
        nLabel                     matlab.ui.control.Label
        D0Spinner                  matlab.ui.control.Spinner
        D0SpinnerLabel             matlab.ui.control.Label
        ProcessButton_2            matlab.ui.control.Button
        InsertImageButton_2        matlab.ui.control.Button
        Image_2                    matlab.ui.control.Image
        ResultImageLabel_2         matlab.ui.control.Label
        OriginalImageLabel_2       matlab.ui.control.Label
        InputImage_2               matlab.ui.control.Image
        ResultImage_2              matlab.ui.control.Image
        ImageFilteringTab          matlab.ui.container.Tab
        ImageNoiseTab              matlab.ui.container.Tab
        ImageDeconvolutionTab      matlab.ui.container.Tab
    end

    
    properties (Access = public)
        originalImage uint8
    end
    
    methods (Access = private)
        
        function InputImageEvent(app, imageContainer)
            % Open file selection dialog for supported formats
            [file, path] = uigetfile({'*.tif;*.bmp;*.png;*.jpg;*.jpeg;*.gif;*.svg', ...
                                      'All Image Files (*.tif, *.bmp, *.png, *.jpg, *.jpeg, *.gif, *.svg)'; ...
                                      '*.*', 'All Files (*.*)'}, ...
                                     'Select an Image File');
            % Check if user selected a file or clicked cancel
            if isequal(file, 0)
                uialert(app.UIFigure, 'No file selected', 'File Selection');
                return;
            end
        
            % Full file path
            filepath = fullfile(path, file);
            app.originalImage = imread(filepath);
            
            % Reset all UIs
            %app.Image.ImageSource = '';
            %app.ResultImage.ImageSource = '';
            %app.InputImage.ImageSource = '';

            % Try to read and convert the image if it's a BMP file
            [~, ~, ext] = fileparts(filepath);
            if strcmpi(ext, '.bmp') || strcmpi(ext, '.tif')
                % Convert BMP to a temporary PNG file
                img = imread(filepath);
                
                % Use tempname to generate a complete file path
                tempfile = [tempname, '.png'];  % Correctly append '.png' to tempname
                
                % Write the image to the temporary file
                imwrite(img, tempfile);
                
                % Update the Image component's ImageSource property
                imageContainer.ImageSource = tempfile;  % Use the complete file path
            else
                % Use the selected file directly if it's already a supported format
                imageContainer.ImageSource = filepath;
            end 
        end
        
        
        function PostProcessImage(app, image, resultContainer)
            tempfile = [tempname, '.png'];
            imwrite(image, tempfile);
            
            if ndims(image) ~= 3
                result = cat(3, image, image, image);
            end

            resultContainer.ImageSource = result;
        end
    end
  

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: InsertImageButton
        function InputImageButtonPushed(app, event)
            app.InputImageEvent(app.InputImage);
        end

        % Callback function
        function OperationDropDownValueChanged(app, event)
            value = app.OperationDropDown.Value;

            if isequal(value, "Image Enhancement")
                app.BrighteningCheckBox.Visible = "on";
                app.MaskEditField.Visible = "on";
                app.NegativeCheckBox.Visible = "on";
                app.LogTransformationCheckBox.Visible = "on";
                app.PowerTransformationCheckBox.Visible = "on";
                app.ContrastStretchingCheckBox.Visible = "on";
                app.aEditField.Visible = "on";
                app.aEditFieldLabel.Visible = "on";
                app.bEditField.Visible = "on";
                app.bEditFieldLabel.Visible = "on";
                app.cEditField.Visible = "on";
                app.cEditFieldLabel.Visible = "on";
                app.cEditField_2.Visible = "on";
                app.cEditField_2Label.Visible = "on";
                app.InputImage.Visible = "off";
                app.InputReferenceImageButton.Visible = "off";
                app.TabGroup_3.Visible = "off";
            end

            if isequal(value, "Histogram Equalization")
                app.BrighteningCheckBox.Visible = "off";
                app.MaskEditField.Visible = "off";
                app.NegativeCheckBox.Visible = "off";
                app.LogTransformationCheckBox.Visible = "off";
                app.PowerTransformationCheckBox.Visible = "off";
                app.ContrastStretchingCheckBox.Visible = "off";
                app.aEditField.Visible = "off";
                app.aEditFieldLabel.Visible = "off";
                app.bEditField.Visible = "off";
                app.bEditFieldLabel.Visible = "off";
                app.cEditField.Visible = "off";
                app.cEditFieldLabel.Visible = "off";
                app.cEditField_2.Visible = "off";
                app.cEditField_2Label.Visible = "off";
                app.InputImage.Visible = "off";
                app.InputReferenceImageButton.Visible = "off";
                app.TabGroup_3.Visible = "off";
            end

            if isequal(value, "Histogram Specification")
                app.BrighteningCheckBox.Visible = "off";
                app.MaskEditField.Visible = "off";
                app.NegativeCheckBox.Visible = "off";
                app.LogTransformationCheckBox.Visible = "off";
                app.PowerTransformationCheckBox.Visible = "off";
                app.ContrastStretchingCheckBox.Visible = "off";
                app.aEditField.Visible = "off";
                app.aEditFieldLabel.Visible = "off";
                app.bEditField.Visible = "off";
                app.bEditFieldLabel.Visible = "off";
                app.cEditField.Visible = "off";
                app.cEditFieldLabel.Visible = "off";
                app.cEditField_2.Visible = "off";
                app.cEditField_2Label.Visible = "off";
                app.InputImage.Visible = "on";
                app.InputReferenceImageButton.Visible = "on";
                app.TabGroup_3.Visible = "on";
            end
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            if isequal(app.originalImage, [])
                uialert(app.UIFigure, 'No input file detected', 'No Input');
                return;
            end

            if isequal(app.MaskEditField.Value, '')
                uialert(app.UIFigure, 'No input mask detected', 'No Input');
                return;
            end

            processedImg = app.originalImage;
            
            mask = str2num(app.MaskEditField.Value);
            processedImg = uint8(convolution(processedImg, mask));

            app.PostProcessImage(processedImg, app.ResultImage);
        end

        % Callback function
        function InputReferenceImageButtonPushed(app, event)
            if isequal(app.Image.ImageSource, '')
                uialert(app.UIFigure, 'Please insert input image first', 'No Input');
                return;
            end
            
            % Open file selection dialog for supported formats
            [file, path] = uigetfile({'*.tif;*.bmp;*.png;*.jpg;*.jpeg;*.gif;*.svg', ...
                                      'All Image Files (*.tif, *.bmp, *.png, *.jpg, *.jpeg, *.gif, *.svg)'; ...
                                      '*.*', 'All Files (*.*)'}, ...
                                     'Select an Image File');
            % Check if user selected a file or clicked cancel
            if isequal(file, 0)
                uialert(app.UIFigure, 'No file selected', 'File Selection');
                return;
            end
        
            % Full file path
            filepath = fullfile(path, file);
            
            % Check if input image and reference image have same color
            % channel and dimension
            if ~isequal(ndims(imread(filepath)), ndims(imread(app.Image.ImageSource)))
                uialert(app.UIFigure, "Reference image must have the same dimension and color channels as input image", 'Input Error');
                return;
            end

            % Reset all UIs
            app.InputImage.ImageSource = '';
            cla(app.UIAxesGray_3);
            cla(app.UIAxesR_3);
            cla(app.UIAxesG_3);
            cla(app.UIAxesB_3);

            % Try to read and convert the image if it's a BMP file
            [~, ~, ext] = fileparts(filepath);
            if strcmpi(ext, '.bmp') || strcmpi(ext, '.tif')
                % Convert BMP to a temporary PNG file
                img = imread(filepath);
                tempPath = fullfile(tempdir, 'ref_image.png');
                imwrite(img, tempPath);
                
                % Update the Image component's ImageSource property
                app.InputImage.ImageSource = tempPath;
            else
                % Use the selected file directly if it's already a supported format
                app.InputImage.ImageSource = filepath;
            end
            
            if ndims(imread(filepath)) == 3
                displayHistogram(imread(app.InputImage.ImageSource), [app.UIAxesR_3, app.UIAxesG_3, app.UIAxesB_3]);
            else
                displayHistogram(imread(app.InputImage.ImageSource), app.UIAxesGray_3);
            end
            
        end

        % Button pushed function: InsertImageButton_2
        function InsertImageButton_2Pushed(app, event)
            app.InputImageEvent(app.InputImage_2);
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            app.originalImage = [];
            
            app.InputImage.ImageSource = '';
            app.ResultImage.ImageSource = '';
            app.InputImage_2.ImageSource = '';
            app.ResultImage_2.ImageSource = '';
        end

        % Button pushed function: ProcessButton_2
        function ProcessButton_2Pushed(app, event)
            if isequal(app.originalImage, [])
                uialert(app.UIFigure, 'No input file detected', 'No Input');
                return;
            end

            processedImg = app.originalImage;
            
            if isequal(app.TabGroup2.SelectedTab.Title, 'Spatial')
                dim = app.MaskDimensionSpinner.Value;
                if isequal(app.FilterDropDown.Value, 'Mean Filter')
                    processedImg = meanFilter(processedImg, dim);
                else
                    processedImg = gaussianFilter(processedImg, dim, app.SigmaSpinner.Value);
                end
            else
                d0 = app.D0Spinner.Value;
                padding = app.PaddingCheckBox.Value;
                if isequal(app.FilterDropDown_2.Value, 'ILPF')
                    disp("ILPF");
                    processedImg = frequencyLPF(processedImg, 'ideal', d0, padding);
                elseif isequal(app.FilterDropDown.Value, 'GLPF')
                    disp("GLPF");
                    processedImg = frequencyLPF(processedImg, 'gaussian', d0, padding);
                else
                    disp("HLPF");
                    processedImg = frequencyLPF(processedImg, 'butterworth', d0, padding, app.NSpinner.Value);
                end
            end

            app.PostProcessImage(processedImg, app.ResultImage_2);
        end

        % Value changed function: FilterDropDown
        function FilterDropDownValueChanged(app, event)
            value = app.FilterDropDown.Value;
            
            if isequal(value, "Gaussian Filter")
                app.SigmaSpinner.Enable = true;
                app.SpinnerLabel.Enable = true;
            else
                app.SigmaSpinner.Enable = false;
                app.SpinnerLabel.Enable = false;
            end
        end

        % Value changed function: FilterDropDown_2
        function FilterDropDown_2ValueChanged(app, event)
            value = app.FilterDropDown_2.Value;
            
            if isequal(value, "BLPF")
                app.NSpinner.Enable = true;
                app.nLabel.Enable = true;
            else
                app.NSpinner.Enable = false;
                app.nLabel.Enable = false;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.ImageConvolutionTab = uitab(app.TabGroup);
            app.ImageConvolutionTab.Title = 'Image Convolution';
            app.ResultImage = uiimage(app.ImageConvolutionTab);
            app.InputImage = uiimage(app.ImageConvolutionTab);
            app.OriginalImageLabel = uilabel(app.ImageConvolutionTab);
            app.OriginalImageLabel.Text = 'Original Image';
            app.ResultImageLabel = uilabel(app.ImageConvolutionTab);
            app.ResultImageLabel.Text = 'Result Image';
            app.MaskEditFieldLabel = uilabel(app.ImageConvolutionTab);
            app.MaskEditFieldLabel.Text = 'Mask';
            app.MaskEditField = uieditfield(app.ImageConvolutionTab, 'text');
            app.Image = uiimage(app.ImageConvolutionTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'ui', 'right_arrow.png');
            app.InsertImageButton = uibutton(app.ImageConvolutionTab, 'push');
            app.InsertImageButton.ButtonPushedFcn = createCallbackFcn(app, @InputImageButtonPushed, true);
            app.InsertImageButton.Text = 'Insert Image';
            app.ProcessButton = uibutton(app.ImageConvolutionTab, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.ImageSmoothingBlurringTab = uitab(app.TabGroup);
            app.ImageSmoothingBlurringTab.Title = 'Image Smoothing & Blurring';
            app.ResultImage_2 = uiimage(app.ImageSmoothingBlurringTab);
            app.InputImage_2 = uiimage(app.ImageSmoothingBlurringTab);
            app.OriginalImageLabel_2 = uilabel(app.ImageSmoothingBlurringTab);
            app.OriginalImageLabel_2.Text = 'Original Image';
            app.ResultImageLabel_2 = uilabel(app.ImageSmoothingBlurringTab);
            app.ResultImageLabel_2.Text = 'Result Image';
            app.Image_2 = uiimage(app.ImageSmoothingBlurringTab);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'ui', 'right_arrow.png');
            app.InsertImageButton_2 = uibutton(app.ImageSmoothingBlurringTab, 'push');
            app.InsertImageButton_2.ButtonPushedFcn = createCallbackFcn(app, @InsertImageButton_2Pushed, true);
            app.InsertImageButton_2.Text = 'Insert Image';
            app.ProcessButton_2 = uibutton(app.ImageSmoothingBlurringTab, 'push');
            app.ProcessButton_2.ButtonPushedFcn = createCallbackFcn(app, @ProcessButton_2Pushed, true);
            app.ProcessButton_2.Text = 'Process';
            app.TabGroup2 = uitabgroup(app.ImageSmoothingBlurringTab);
            app.SpatialTab = uitab(app.TabGroup2);
            app.SpatialTab.Title = 'Spatial';
            app.MaskDimensionSpinnerLabel = uilabel(app.SpatialTab);
            app.MaskDimensionSpinnerLabel.Text = 'Mask Dimension';
            app.MaskDimensionSpinner = uispinner(app.SpatialTab);
            app.MaskDimensionSpinner.Step = 2;
            app.MaskDimensionSpinner.Limits = [3 Inf];
            app.MaskDimensionSpinner.Value = 3;
            app.SpinnerLabel = uilabel(app.SpatialTab);
            app.SpinnerLabel.Enable = 'off';
            app.SpinnerLabel.Text = 'σ';
            app.SigmaSpinner = uispinner(app.SpatialTab);
            app.SigmaSpinner.Limits = [1 Inf];
            app.SigmaSpinner.Enable = 'off';
            app.SigmaSpinner.Value = 3;
            app.FilterDropDownLabel = uilabel(app.SpatialTab);
            app.FilterDropDownLabel.Text = 'Filter';
            app.FilterDropDown = uidropdown(app.SpatialTab);
            app.FilterDropDown.Items = {'Mean Filter', 'Gaussian Filter'};
            app.FilterDropDown.ValueChangedFcn = createCallbackFcn(app, @FilterDropDownValueChanged, true);
            app.FilterDropDown.Value = 'Mean Filter';
            app.FrequencyTab = uitab(app.TabGroup2);
            app.FrequencyTab.Title = 'Frequency';
            app.D0SpinnerLabel = uilabel(app.FrequencyTab);
            app.D0SpinnerLabel.Text = 'D0';
            app.D0Spinner = uispinner(app.FrequencyTab);
            app.D0Spinner.Limits = [1 Inf];
            app.D0Spinner.Value = 50;
            app.nLabel = uilabel(app.FrequencyTab);
            app.nLabel.Enable = 'off';
            app.nLabel.Text = 'n';
            app.NSpinner = uispinner(app.FrequencyTab);
            app.NSpinner.Limits = [1 Inf];
            app.NSpinner.Enable = 'off';
            app.NSpinner.Value = 1;
            app.FilterDropDown_2Label = uilabel(app.FrequencyTab);
            app.FilterDropDown_2Label.Text = 'Filter';
            app.FilterDropDown_2 = uidropdown(app.FrequencyTab);
            app.FilterDropDown_2.Items = {'ILPF', 'GLPF', 'BLPF'};
            app.FilterDropDown_2.ValueChangedFcn = createCallbackFcn(app, @FilterDropDown_2ValueChanged, true);
            app.FilterDropDown_2.Value = 'ILPF';
            app.PaddingCheckBox = uicheckbox(app.FrequencyTab);
            app.PaddingCheckBox.Text = 'Padding';
            app.PaddingLabel = uilabel(app.FrequencyTab);
            app.PaddingLabel.Text = '* Image will be padded 2x it''s original size';
            app.ImageFilteringTab = uitab(app.TabGroup);
            app.ImageFilteringTab.Title = 'Image Filtering';
            app.ImageNoiseTab = uitab(app.TabGroup);
            app.ImageNoiseTab.Title = 'Image Noise';
            app.ImageDeconvolutionTab = uitab(app.TabGroup);
            app.ImageDeconvolutionTab.Title = 'Image Deconvolution';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end